classdef finalProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        NumUnassignedT        matlab.ui.control.NumericEditField
        BegindetectionButton  matlab.ui.control.Button
        LoadFolderButton      matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        MainDir; % Main directory of the images & data
        numFrames;
        images; %array of all images in folder
        greyImages; %array of all gry images
        BinImages; %array of all binary images
        BBoxes;
        Tracks;
        Covariances;
        rows;
        cols;
        predictions;
        qk = [1, 0,0,0,0,0;
              0,1,0,0,0,0;
              0,0,1,0,0,0;
              0,0,0,1,0,0;
              0,0,0,0,1,0;
              0,0,0,0,0,1];
        hk = [1, 0,0,0,0,0; 0,1,0,0,0,0];
        rk = [1, 0: 0, 1];
        fk = [1,0,1,0,0.5,0;
              0,1,0,1,0,0.5;
              0,0,1,0,1,0;
              0,0,0,1,0,1;
              0,0,0,0,1,0;
              0,0,0,0,0,1];
        I = eye(6);
    end
    
    methods (Access = private)
        
        function ObjectDetection(app, frames)
             for i= 2: frames - 1
                %intialise images at index i, i-1 and i+1
                gry1 = app.greyImages{1,i};
                gry2 = app.greyImages{1, i-1};
                gry3 = app.greyImages{1, i+1};
                %Split images into regions of 30x30 pixels
                [rows, cols] = size(gry1);
                wholeBlockRows = floor(rows/30);
                blockVectorR = [30*ones(1, wholeBlockRows), rem(rows, 30)];
                wholeBlockCols = floor(cols/30);
                blockVectorC = [30 * ones(1, wholeBlockCols), rem(cols, 30)];
                ca1 = mat2cell(gry1, blockVectorR, blockVectorC);
                ca2 = mat2cell(gry2, blockVectorR, blockVectorC);
                ca3 = mat2cell(gry3, blockVectorR, blockVectorC);
                numBlocksR = size(ca1, 1);
                numBlocksC = size(ca1, 2);
                binary_array = cell(numBlocksR, numBlocksC);
                %Apply algorithm at each block
                for r = 1: numBlocksR
                    for c = 1: numBlocksC
                        diff1 = abs(double(ca1{r,c}) - double(ca2{r,c}));
                        diff2 = abs(double(ca1{r,c})- double(ca3{r,c}));
                        avg1 = mean(diff1, "all");
                        avg2 = mean(diff2, "all");
                        thresh1 = -log(0.05)*avg1;
                        thresh2 = -log(0.05)*avg2;
                        binary1 = diff1 >thresh1;
                        binary2 = diff2 >thresh2;
                        binary_array{r,c} = binary1.*binary2;
                        
                    end
                end
                 %display binarized image
                 final = cell2mat(binary_array);
                 app.BinImages{1, i-1} = final;
             end
        end
        
        function [centroid, bbox] = ObjectDiscrimination(app, binImage, gryImage)
            Hblob = vision.BlobAnalysis('MinimumBlobArea', 4, 'LabelMatrixOutputPort', 300, 'MaximumCount', 300);
            [area, centroid, bbox] = Hblob(imbinarize(binImage));
            %blobResults = [area, centroid, bbox];
            [app.rows, app.cols] = size(gryImage);
            region_grown = zeros(app.rows, app.cols);
            bboxes = zeros(size(centroid, 1), 6);
            for row = 1:size(centroid,1)
                centroidCol = round(centroid(row, 1));
                centroidRow = round(centroid(row, 2));
                lowerRindex = max(1, centroidRow - 5);
                upperRindex = min(app.rows, centroidRow + 5);
                lowerCindex = max(1, centroidCol - 5);
                upperCindex = min(app.cols, centroidCol + 5);
                gray_window = gryImage(lowerRindex: upperRindex, lowerCindex : upperCindex);
                bin_window = binImage(lowerRindex: upperRindex, lowerCindex : upperCindex);
                bboxes(row, :) = [centroidCol, centroidRow, lowerCindex, lowerRindex, upperCindex - lowerCindex, upperRindex - lowerRindex];
                pixel_values = zeros(numel(gray_window), 1);
                a = 1;
                for m = 1:size(gray_window, 1)
                    for n = 1:size(gray_window, 2)
                        if bin_window(m,n) == 1
                            pixel_values(a) = gray_window(m,n);
                            a = a + 1;
                        end
                    end
                end
                pixel_values = pixel_values(1:a-1);
                mu = mean(double(pixel_values), "all");
                sigma = std(double(pixel_values), 1) / sqrt(size(pixel_values, 1));
                x = norminv([0.005, 0.995], mu, sigma);
                for m = 1:size(gray_window, 1)
                    for n = 1:size(gray_window, 2)
                        if gray_window(m,n) > x(1) && gray_window(m,n) < x(2)
                            bin_window(m,n) = 1;
                        else
                            bin_window(m,n) = 0;
                        end
                    end
                end
                region_grown(lowerRindex:upperRindex, lowerCindex: upperCindex) = bin_window;
            end
            
            %Morphological cues
            Hblob = vision.BlobAnalysis('MinimumBlobArea', 10, 'LabelMatrixOutputPort', 300, 'MaximumCount', 300, "MajorAxisLengthOutputPort", true, "EccentricityOutputPort", true, "ExtentOutputPort", true);
            [area, centroid, bbox, majoraxis, eccentricity, extent] = Hblob(imbinarize(region_grown));
            toRemove = [];
            for row = 1:size(centroid, 1)
                %filter by extent
                if extent(row) > 0.8
                    toRemove = [toRemove; row];
                    continue;
                end
                %filter by eccentricity
                if eccentricity(row) < 0.5
                    toRemove = [toRemove; row];
                    continue;
                end
                %filter by area
                if area(row) > 40
                    toRemove = [toRemove; row];
                    continue;
                end
%                 filter by major axis
                if majoraxis(row) > 12
                    toRemove = [toRemove; row];
                    continue;
                end
            end
            centroid(toRemove, :) = [];
            bbox(toRemove, :) = [];
            %show result
%             for row = 1:size(centroid,1)
%                 posx = bbox(row, 1);
%                 posy = bbox(row, 2);
%                 width = bbox(row, 3);
%                 height = bbox(row, 4);
%                 rectangle(app.UIAxes, "Position", [posx, posy, width, height], "EdgeColor", 'r');
%                 rectangle(app.UIAxes, "Position", [bbox(row, 1), bbox(row, 2), bbox(row, 3), bbox(row, 4)], "EdgeColor", 'b');
%             end           
        end
        
        function [assignment, unassignedTracks, unassignedDetections] = TrackHypothesis(app,predictions, detections)
            cost = zeros(size(predictions,1), size(detections,1));
            for i = 1: size(predictions,1)
                diff = detections - repmat(predictions(i, :), [size(detections,1), 1]);
                cost(i, :) = sqrt(sum(diff.^2,2));
            end
            [assignment, unassignedTracks, unassignedDetections] = assignDetectionsToTracks(cost, 0.7);
        end
        
        function KalmanFilter(app, assignment, unassignedTracks, unassignedDetections, bbox, centroids, i)
                if ~isempty(unassignedDetections)
                    for b = 1: length(unassignedDetections)
                        %initialise tracks
                        unassignedIndex = unassignedDetections(b);
                        app.Tracks{1, unassignedIndex} = [centroids(unassignedIndex, 2); centroids(unassignedIndex,1);0;0;0;0];
                        app.Covariances{1, unassignedIndex} = app.qk;
                        app.predictions(unassignedIndex, 1:2) = app.Tracks{1,unassignedIndex}(1:2,1);
                    end
                end
                if ~isempty(assignment)
                    for c=1: length(assignment)
                        %update step for kalman filter, display and then predict
                        assignedIndex = assignment(c);
                        %compute error
                        yk = centroids(assignedIndex) - app.hk*app.Tracks{1, assignedIndex};
                        %compute covariance of innovation
                        sk = app.hk*app.Covariances{1, assignedIndex}*transpose(app.hk)+ app.rk;
                        %compute kalman gain
                        kk = app.Covariances{1, assignedIndex}*transpose(app.hk)*sk^(-1);
                        %compute updated state estimate
                        app.Tracks{1, assignedIndex} = app.Tracks{1, assignedIndex} + kk*yk;
                        %compute updated state covariance
                        app.Covariances{1, assignedIndex} = (app.I - kk*app.hk)*app.Covariances{1, assignedIndex};
                        
                    end
                end
                if ~isempty(unassignedTracks)
                    for d =1: length(unassignedTracks)
                        %nearest search
                        unassignedTindex = unassignedTracks(d);
                        trackunassigned = app.Tracks{1, unassignedTindex};
                        %location of object from previous frame
                        previoustrack = app.fk^(-1)*trackunassigned;
                        %create a 5x5 search region
                        lowerRindex = max(1, previoustrack(1,1) - 2);
                        upperRindex = min(app.rows,previoustrack(1,1) +2 );
                        lowerCindex = max(1, previoustrack (2,1) - 2);
                        upperCindex = min(app.cols, previoustrack(2,1)+2);
                        previousFrame = app.greyImages{1,i-1}(lowerRindex:upperRindex, lowerCindex:upperCindex); %search window in previous frame
                        currentFrame = app.greyImages{1, i}(lowerRindex:upperRindex, lowerCindex:upperCindex); %search window in current frame
                        %compute SAD
                        difference = abs(currentFrame - previousFrame);
                        SAD = sum(difference, 'all');
                        if SAD <= 150 %average individual pixel variation of 6 is maximal variation allowed
                            %hypothesis found, update step for Kalman
                            yk = centroids(unassignedTindex) - app.hk*app.Tracks{1, unassignedTindex};
                            sk = app.hk * app.Covariances{1, unassignedTindex}*transpose(app.hk)+ app.rk;
                            kk = app.Covariances{1, unassignedTindex}*(transpose(app.hk))*sk^(-1);
                            app.Tracks{1, unassignedTindex} = app.Tracks{1, unassignedTindex} + kk*yk;
                            app.Covariances = (app.I - kk*app.hk)*app.Covariances;
                        else
                            %remove tracks
                            app.Tracks{1,unassignedTindex} = [];
                        end
                    end
                end
                imshow(app.images{1,i},'Parent', app.UIAxes);
                %%%NEEDS REVISION %%%%%%
                for e =1 : length(bbox)
                    posx = bbox(e, 1);
                    posy = bbox(e,2);
                    width = bbox(e, 3);
                    height = bbox(e,4);
                    rectangle(app.UIAxes, "Position", [posx, posy,width, height],"EdgeColor", 'y');
                end
                drawnow;
                %predict next track
                for f=1: length(app.Tracks)
                    app.Tracks{1,f} = app.fk*app.Tracks{1,f};
                    app.Covariances{1,f} = app.fk*app.Covariances{1,f}*transpose(app.fk)+app.qk;
                end
            end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadFolderButton
        function LoadFolderButtonPushed(app, event)
            app.MainDir = uigetdir("Select Numbered Folder");
            %get image files and number of images
            image_files = dir(sprintf('%s\\img\\*.jpg', app.MainDir));
            for i = 1: length(image_files)
                path = strcat(image_files(i).folder, '\', image_files(i).name) ;
                im = imread(path); %store all images in cell array
                app.images{1, i} = im;
                gry = rgb2gray(im);
                app.greyImages{1,i} = gry; %store greyscale versions of images in cell array
            end
            app.numFrames = length(image_files);
            [app.rows, app.cols] = size(app.greyImages{1,1}); %assumes all images in file are same size
        end

        % Button pushed function: BegindetectionButton
        function BegindetectionButtonPushed(app, event)
            % Object Detection
            ObjectDetection(app, app.numFrames);
            for frame=1: length(app.BinImages) -1
                [centroids, bbox] = ObjectDiscrimination(app, app.BinImages{1,frame}, app.greyImages{1,frame});
                %Hypothesis
                detections = centroids;
                [assignment, unassignedTracks, unassignedDetections] = TrackHypothesis(app, app.predictions, detections);
%                 app.NumUnassignedT.Value = length(unassignedDetections);
                %Kalman filter
                KalmanFilter(app, assignment, unassignedTracks, unassignedDetections, bbox, centroids, frame);
                pause(1);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.LoadFolderButton = uibutton(app.UIFigure, 'push');
            app.LoadFolderButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFolderButtonPushed, true);
            app.LoadFolderButton.Text = 'Load Folder';
            app.BegindetectionButton = uibutton(app.UIFigure, 'push');
            app.BegindetectionButton.ButtonPushedFcn = createCallbackFcn(app, @BegindetectionButtonPushed, true);
            app.BegindetectionButton.Text = 'Begin detection';
            app.NumUnassignedT = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = finalProject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end